classdef photostore < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        PhotoStoreMenu     matlab.ui.container.Menu
        Menu_5             matlab.ui.container.Menu
        Menu_6             matlab.ui.container.Menu
        Menu_4             matlab.ui.container.Menu
        Menu               matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        Menu_3             matlab.ui.container.Menu
        Button_25          matlab.ui.control.Button
        Button_24          matlab.ui.control.Button
        Button_23          matlab.ui.control.Button
        Button_22          matlab.ui.control.Button
        TextArea_2         matlab.ui.control.TextArea
        Image              matlab.ui.control.Image
        TextArea           matlab.ui.control.TextArea
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        Button             matlab.ui.control.Button
        BEditField         matlab.ui.control.NumericEditField
        BEditFieldLabel    matlab.ui.control.Label
        GEditField         matlab.ui.control.NumericEditField
        GEditFieldLabel    matlab.ui.control.Label
        REditField         matlab.ui.control.NumericEditField
        REditFieldLabel    matlab.ui.control.Label
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button_4           matlab.ui.control.RadioButton
        Button_3           matlab.ui.control.RadioButton
        Button_2           matlab.ui.control.RadioButton
        Panel              matlab.ui.container.Panel
        RGBTab             matlab.ui.container.Tab
        Panel_4            matlab.ui.container.Panel
        BSlider            matlab.ui.control.Slider
        BSliderLabel       matlab.ui.control.Label
        GSlider            matlab.ui.control.Slider
        GSliderLabel       matlab.ui.control.Label
        RSlider            matlab.ui.control.Slider
        RSliderLabel       matlab.ui.control.Label
        RGBPanel           matlab.ui.container.Panel
        BCheckBox          matlab.ui.control.CheckBox
        GCheckBox          matlab.ui.control.CheckBox
        RCheckBox          matlab.ui.control.CheckBox
        UIAxes2            matlab.ui.control.UIAxes
        Tab_2              matlab.ui.container.Tab
        Button_18          matlab.ui.control.Button
        ButtonGroup_3      matlab.ui.container.ButtonGroup
        Slider             matlab.ui.control.Slider
        EditField          matlab.ui.control.NumericEditField
        EditFieldLabel     matlab.ui.control.Label
        ButtonGroup_2      matlab.ui.container.ButtonGroup
        Button_17          matlab.ui.control.RadioButton
        Button_27          matlab.ui.control.RadioButton
        Button_26          matlab.ui.control.RadioButton
        Tab_3              matlab.ui.container.Tab
        Button_19          matlab.ui.control.Button
        Button_6           matlab.ui.control.StateButton
        Tab_4              matlab.ui.container.Tab
        Button_7           matlab.ui.control.Button
        ButtonGroup_4      matlab.ui.container.ButtonGroup
        Button_9           matlab.ui.control.RadioButton
        Button_8           matlab.ui.control.RadioButton
        Tab_5              matlab.ui.container.Tab
        Button_10          matlab.ui.control.Button
        ButtonGroup_5      matlab.ui.container.ButtonGroup
        RSlider_2          matlab.ui.control.Slider
        RSlider_2Label     matlab.ui.control.Label
        RSpinner           matlab.ui.control.Spinner
        RSpinnerLabel      matlab.ui.control.Label
        REditField_3       matlab.ui.control.NumericEditField
        REditField_3Label  matlab.ui.control.Label
        Tab_6              matlab.ui.container.Tab
        ButtonGroup_9      matlab.ui.container.ButtonGroup
        Button_21          matlab.ui.control.RadioButton
        Button_20          matlab.ui.control.RadioButton
        ButtonGroup_8      matlab.ui.container.ButtonGroup
        RSlider_3          matlab.ui.control.Slider
        RSlider_3Label     matlab.ui.control.Label
        RSpinner_2         matlab.ui.control.Spinner
        RSpinner_2Label    matlab.ui.control.Label
        REditField_4       matlab.ui.control.NumericEditField
        REditField_4Label  matlab.ui.control.Label
        Button_11          matlab.ui.control.Button
        Tab_7              matlab.ui.container.Tab
        Button_12          matlab.ui.control.Button
        ButtonGroup_7      matlab.ui.container.ButtonGroup
        EditField_3        matlab.ui.control.NumericEditField
        EditField_3Label   matlab.ui.control.Label
        EditField_2        matlab.ui.control.NumericEditField
        EditField_2Label   matlab.ui.control.Label
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end


    properties (Access = private)
        originalpicture; % 原始图片
        changedpicture; % 编辑后照片
    end

    methods (Access = private)
        % 灰度化函数
        function y = mygaray(~,x,k,rp,gp,bp)
            r=x(:,:,1);
            g=x(:,:,2);
            b=x(:,:,3);
            if k==1 % 最大值灰度化
                y=maxgray(r,g,b);
            elseif k==2 % 平均值灰度化
                y=avergray(r,g,b);
            else % 比例灰度化
                y=propgray(r,g,b,rp,gp,bp);
            end

            function y = maxgray(r,g,b)
                y=max(r,g);
                y=max(y,b);
            end

            function y = avergray(r,g,b)
                rp=double(r);
                gp=double(g);
                bp=double(b);
                y=(rp+gp+bp)/3;
                y=uint8(y);
            end

            function y = propgray(r,g,b,rp,gp,bp)
                y=r*rp+g*gp+b*bp;
            end
        end

        % 绘制rgb三分量曲线
        function show = rgbshow(app)
            % 拆分三分量
            Rim=app.changedpicture(:,:,1);
            Gim=app.changedpicture(:,:,2);
            Bim=app.changedpicture(:,:,3);
            % 绘制在一个坐标中
            histogram(app.UIAxes2, Rim, 'FaceColor', [1 0 0], 'EdgeColor', 'none');
            hold(app.UIAxes2,'on');
            histogram(app.UIAxes2, Gim, 'FaceColor', [0 1 0], 'EdgeColor', 'none');
            histogram(app.UIAxes2, Bim, 'FaceColor', [0 0 1], 'EdgeColor', 'none');
            hold(app.UIAxes2,'off');
        end

        function y = Invert(~,x)
            s=size(x);
            r=size(s);
            if r==3
                y(:,:,1)=255-x(:,:,1);
                y(:,:,2)=255-x(:,:,2);
                y(:,:,3)=255-x(:,:,3);
            else
                y=255-x;
            end
        end

        % 浮雕函数
        function y = relief(~,x,k,t)
            %x:输入图像 k:选择做灰度浮雕还是彩色浮雕 t:浮雕距离
            s=size(x);
            if k==0
                for i=1:s(1)-t
                    for j=1:s(2)-t
                        y(i,j)=x(i,j)-x(i+t,j+t)+128;
                    end
                end
            else
                r=x(:,:,1);
                g=x(:,:,2);
                b=x(:,:,3);
                if k==1
                    %灰度浮雕
                    p=propgary(r,g,b);
                    for i=1:s(1)-t
                        for j=1:s(2)-t
                            y(i,j)=p(i,j)-p(i+t,j+t)+128;
                        end
                    end
                else
                    %彩色浮雕
                    for i=1:s(1)-t
                        for j=1:s(2)-t
                            y(i,j,:)=x(i,j,:)-x(i+t,j+t,:)+128;
                        end
                    end
                end
            end

            function y = propgary(r,g,b)
                y=r*0.299+g*0.587+b*0.114;
            end
        end

        % 倒影函数 x:输入图像 r:模糊距离
        function y = reflection(x,r)
            y=x;
            s=size(x);
            for e=1:2
                for i=1:s(1)-r
                    for j=1:s(2)-r
                        pos=ceil(rand(1,2)*r);
                        y(i,j,:)=y(i+pos(1),j+pos(2),:);
                    end
                end

                for j=1:s(2)
                    for i=s(1)-r:s(1)
                        pos=ceil(rand(1,2)*r);
                        y(i,j,:)=y(i-pos(1),j,:);
                    end
                end

                for i=1:s(1)
                    for j=s(2)-r:s(2)
                        pos=ceil(rand(1,2)*r);
                        y(i,j,:)=y(i,j-pos(2),:);
                    end
                end
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(app.originalpicture)
                errordlg('请上传照片','ERROR');
                Button_13Pushed(app);
                pause(1);
            end
        end

        % Button pushed function: Button_22
        function Button_13Pushed(app, event)
            [filename,path] = uigetfile({'*.jpg';'*.png';'*.bmp'},'选择处理图像');
            if isequal(filename,0)||isequal(path,0) % 容错提示
                errordlg('上传失败','ERROR');
                Button_13Pushed(app);
            else
                file=[path filename]; % 合成文件路径
                app.originalpicture=imread(file);
                app.changedpicture=app.originalpicture;
                % assignin("base",'x',app.changedpicture);
                imshow(app.originalpicture,'Parent',app.UIAxes); %在绘图区1显示原始照片
                imshow(app.changedpicture,'Parent',app.UIAxes_2);
            end
        end

        % Button pushed function: Button_23
        function Button_14Pushed(app, event)
            [filename,path] = uiputfile({'*.jpg';'*.png';'*.bmp'},'保存图像','untitled.png'); % 选择保存路径
            if ~isequal([filename,path],[0,0])
                mypng=fullfile(path,filename);
                imwrite(app.changedpicture,mypng); % 在用户选择的路径保存
                msgbox('保存成功');
            else
                msgbox('保存失败');
            end
        end

        % Button pushed function: Button_24
        function Button_15Pushed(app, event)
            app.changedpicture=app.originalpicture;
            imshow(app.changedpicture,'Parent',app.UIAxes_2);
            % 还原滑块参数
            app.RSlider.Value=0;
            app.GSlider.Value=0;
            app.BSlider.Value=0;
        end

        % Button pushed function: Button_25
        function Button_16Pushed(app, event)
            answer=questdlg('确定退出PhotoStore?','提示','确定','取消','取消'); % 后悔药提示
            switch answer
                case '确定'
                    delete(app);
                case '取消'
                    return
            end
        end

        % Menu selected function: PhotoStoreMenu
        function PhotoStoreMenuSelected(app, event)
            
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            [filename,path] = uigetfile({'*.jpg';'*.png';'*.bmp'},'选择处理图像');
            if isequal(filename,0)||isequal(path,0) % 容错提示
                errordlg('上传失败','ERROR');
                return;
            else
                file=[path filename]; % 合成文件路径
                app.originalpicture=imread(file);
                app.changedpicture=app.originalpicture;
                assignin("base",'x',app.changedpicture);
                imshow(app.originalpicture,'Parent',app.UIAxes); %在绘图区1显示原始照片
            end
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            [filename,path] = uiputfile({'*.jpg';'*.png';'*.bmp'},'保存图像','untitled.png'); % 选择保存路径
            if ~isequal([filename,path],[0,0])
                mypng=fullfile(path,filename);
                imwrite(app.changedpicture,mypng); % 在用户选择的路径保存
                msgbox('保存成功');
            else
                msgbox('保存失败');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % 获取用户输入的比例
            rp=app.REditField.Value;
            gp=app.GEditField.Value;
            bp=app.BEditField.Value;
            % 判断图片颜色属性
            size1=size(app.originalpicture);
            size2=size(size1);
            if size2~=3 % 当前照片为灰度图 报错
                errordlg('只有彩色图片才能灰度化','ERROR');
            else
                if app.Button_2==1 % 最大值灰度
                    app.changedpicture=app.mygaray(app.originalpicture,1);
                elseif app.Button_3==1 % 平均值灰度
                    app.changedpicture=app.mygaray(app.originalpicture,2);
                else % 比例灰度
                    app.changedpicture=app.mygaray(app.originalpicture,3,rp,gp,bp);
                end
                imshow(app.changedpicture,'Parent',app.UIAxes_2); % 显示灰度图
            end
        end

        % Button down function: UIAxes_2
        function UIAxes_2ButtonDown(app, event)

        end

        % Value changed function: RCheckBox
        function RCheckBoxValueChanged(app, event)
            % 按钮状态
            Rvalue = app.RCheckBox.Value;
            Gvalue = app.GCheckBox.Value;
            Bvalue = app.BCheckBox.Value;
            % 分图层
            Rim=app.originalpicture(:,:,1);
            Gim=app.originalpicture(:,:,2);
            Bim=app.originalpicture(:,:,3);
            % 合成图层 显示
            if Rvalue==1
                app.changedpicture(:,:,1)=Rim;
            else
                app.changedpicture(:,:,1)=0;
            end
            if Gvalue==1
                app.changedpicture(:,:,2)=Gim;
            else
                app.changedpicture(:,:,2)=0;
            end
            if Bvalue==1
                app.changedpicture(:,:,3)=Bim;
            else
                app.changedpicture(:,:,3)=0;
            end
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Value changed function: GCheckBox
        function GCheckBoxValueChanged(app, event)
            % 按钮状态
            Rvalue = app.RCheckBox.Value;
            Gvalue = app.GCheckBox.Value;
            Bvalue = app.BCheckBox.Value;
            % 分图层
            Rim=app.originalpicture(:,:,1);
            Gim=app.originalpicture(:,:,2);
            Bim=app.originalpicture(:,:,3);
            % 合成图层 显示
            if Rvalue==1
                app.changedpicture(:,:,1)=Rim;
            else
                app.changedpicture(:,:,1)=0;
            end
            if Gvalue==1
                app.changedpicture(:,:,2)=Gim;
            else
                app.changedpicture(:,:,2)=0;
            end
            if Bvalue==1
                app.changedpicture(:,:,3)=Bim;
            else
                app.changedpicture(:,:,3)=0;
            end
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Value changed function: BCheckBox
        function BCheckBoxValueChanged(app, event)
            % 按钮状态
            Rvalue = app.RCheckBox.Value;
            Gvalue = app.GCheckBox.Value;
            Bvalue = app.BCheckBox.Value;
            % 分图层
            Rim=app.originalpicture(:,:,1);
            Gim=app.originalpicture(:,:,2);
            Bim=app.originalpicture(:,:,3);
            % 合成图层 显示
            if Rvalue==1
                app.changedpicture(:,:,1)=Rim;
            else
                app.changedpicture(:,:,1)=0;
            end
            if Gvalue==1
                app.changedpicture(:,:,2)=Gim;
            else
                app.changedpicture(:,:,2)=0;
            end
            if Bvalue==1
                app.changedpicture(:,:,3)=Bim;
            else
                app.changedpicture(:,:,3)=0;
            end
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            value = app.RSlider.Value;
            app.changedpicture(:,:,1)=app.originalpicture(:,:,1)+value;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            changingValue = event.Value;
            app.changedpicture(:,:,1)=app.originalpicture(:,:,1)+changingValue;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            value = app.GSlider.Value;
            app.changedpicture(:,:,2)=app.originalpicture(:,:,2)+value;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Value changing function: GSlider
        function GSliderValueChanging(app, event)
            changingValue = event.Value;
            app.changedpicture(:,:,2)=app.originalpicture(:,:,2)+changingValue;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            value = app.BSlider.Value;
            app.changedpicture(:,:,3)=app.originalpicture(:,:,3)+value;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Value changing function: BSlider
        function BSliderValueChanging(app, event)
            changingValue = event.Value;
            app.changedpicture(:,:,3)=app.originalpicture(:,:,3)+changingValue;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.rgbshow();
        end

        % Button down function: RGBTab
        function RGBTabButtonDown(app, event)
            app.changedpicture=app.originalpicture;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            size1=size(app.originalpicture);
            size2=size(size1);
            if size2~=3 % 当前照片为灰度图 报错
                app.RGBPanel.Enable='off';
                app.Panel_4.Enable='off';
                errordlg('只有彩色图片才能进行分量','ERROR');
            else
                app.RGBPanel.Enable='on';
                app.Panel_4.Enable='on';
                app.rgbshow();
            end
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            method1=app.Button_27.Value;
            method2=app.Button_17.Value;
            T=app.EditField.Value;
            size1=size(app.originalpicture);
            size2=size(size1);
            if size2~=3
                temp=app.originalpicture;
            else
                temp=rgb2gray(app.originalpicture);
            end
            if method1==1 % adaptive二值化
                app.changedpicture=imbinarize(temp,'adaptive');
            elseif method2==1 % 输入阈值二值化
                app.changedpicture=imbinarize(temp,T);
            else  % global二值化
                app.changedpicture=imbinarize(temp,'global');
            end
            imshow(app.changedpicture,'parent',app.UIAxes_2);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.EditField.Value=value;
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            value = app.Button_6.Value;
            app.Button_19.Enable='on';
            app.changedpicture=Invert(app.originalpicture);
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.Button_6.Enable='off';
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.Button_6.Enable='on';
            app.changedpicture=Invert(app.changedpicture);
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            app.Button_19.Enable='off';
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.Button_8.Value==1
                app.changedpicture=flipud(app.originalpicture);
            else
                app.changedpicture=fliplr(app.originalpicture);
            end
            imshow(app.changedpicture,'parent',app.UIAxes_2);
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            r=app.REditField_3.Value;
            app.changedpicture=reflection(app.originalpicture,r);
            imshow(app.changedpicture,'parent',app.UIAxes_2);
        end

        % Value changing function: RSlider_2
        function RSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            changingValue=floor(changingValue);
            app.RSpinner.Value=changingValue;
            app.REditField_3.Value=changingValue;
        end

        % Value changing function: RSpinner
        function RSpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.RSlider_2.Value=changingValue;
            app.REditField_3.Value=changingValue;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            r=app.REditField_4.Value;
            size1=size(app.originalpicture);
            size2=size(size1);
            if size2==3 % 当前照片为灰度图 报错
                if app.Button_20.Value==1
                    app.changedpicture=app.relief(app.originalpicture,1,r);
                end
                app.changedpicture=app.relief(app.originalpicture,2,r);
            else
                app.changedpicture=app.relief(app.originalpicture,0,r);
            end
            imshow(app.changedpicture,'parent',app.UIAxes_2);
        end

        % Value changing function: RSpinner_2
        function RSpinner_2ValueChanging(app, event)
            changingValue = event.Value;
            app.RSlider_3.Value=changingValue;
            app.REditField_4.Value=changingValue;
        end

        % Value changing function: RSlider_3
        function RSlider_3ValueChanging(app, event)
            changingValue = event.Value;
            changingValue=floor(changingValue);
            app.RSpinner_2.Value=changingValue;
            app.REditField_4.Value=changingValue;
        end

        % Button down function: Tab_6
        function Tab_6ButtonDown(app, event)
            app.changedpicture=app.originalpicture;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
            size1=size(app.originalpicture);
            size2=size(size1);
            if size2~=3 
                app.Button_20.Enable='off';
            else
                app.Button_20.Enable='on';
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            x=app.EditField_2.Value;
            y=app.EditField_3.Value; 
            temp=0;
            app.changedpicture=imresize(app.originalpicture,x*y);
            temp=app.changedpicture;
            for j=1:y-1
                temp=cat(2,temp,app.changedpicture);
            end
            app.changedpicture=temp;
            for i=1:x-1
                app.changedpicture=cat(1,app.changedpicture,temp);
            end
            imshow(app.changedpicture,'parent',app.UIAxes_2);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            msgbox({'     PhotoStore是一款图像处理应用';'   请点击右侧编辑选项进行图像处理';'          图像处理效果不进行叠加';'如需多重效果请保存为新图片后再处理'},'帮助');
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            answer=questdlg('确定退出PhotoStore?','提示','确定','取消','取消'); % 后悔药提示
            switch answer
                case '确定'
                    delete(app);
                case '取消'
                    return
            end
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            msgbox({'刘家彤编写';'首都师范大学';'信息工程学院';'2023年1月5日'},'作者');
        end

        % Button down function: Tab
        function TabButtonDown(app, event)
            app.changedpicture=app.originalpicture;
            imshow(app.changedpicture,'parent',app.UIAxes_2);
        end

        % Value changed function: REditField_4
        function REditField_4ValueChanged(app, event)
            changingValue = app.REditField_4.Value;
            app.RSlider_3.Value=changingValue;
            app.RSpinner_2.Value=changingValue;
        end

        % Value changed function: REditField_3
        function REditField_3ValueChanged(app, event)
            changingValue = app.REditField_3.Value;
            app.RSlider_2.Value=changingValue;
            app.RSpinner.Value=changingValue;
        end

        % Button down function: Tab_3
        function Tab_3ButtonDown(app, event)
            app.Button_19.Enable='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PhotoStoreMenu = uimenu(app.UIFigure);
            app.PhotoStoreMenu.MenuSelectedFcn = createCallbackFcn(app, @PhotoStoreMenuSelected, true);
            app.PhotoStoreMenu.Text = 'PhotoStore';
            app.Menu_5 = uimenu(app.PhotoStoreMenu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '帮助';
            app.Menu_6 = uimenu(app.PhotoStoreMenu);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '作者';
            app.Menu_4 = uimenu(app.PhotoStoreMenu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '退出';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '导入图片';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '保存图片';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图像')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '编辑图像')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes_2ButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '    灰度化';
            app.Tab.ButtonDownFcn = createCallbackFcn(app, @TabButtonDown, true);
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '灰度比例';
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.Title = '灰度化方法';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '最大值灰度化';
            app.Button_2.Value = true;
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '平均值灰度化';
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '比例灰度化';
            app.REditFieldLabel = uilabel(app.Tab);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.Tab, 'numeric');
            app.REditField.Value = 0.3;
            app.GEditFieldLabel = uilabel(app.Tab);
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.Tab, 'numeric');
            app.GEditField.Value = 0.59;
            app.BEditFieldLabel = uilabel(app.Tab);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.Tab, 'numeric');
            app.BEditField.Value = 0.11;
            app.Button = uibutton(app.Tab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '进行灰度';
            app.RGBTab = uitab(app.TabGroup);
            app.RGBTab.Title = '     RGB';
            app.RGBTab.ButtonDownFcn = createCallbackFcn(app, @RGBTabButtonDown, true);
            app.UIAxes2 = uiaxes(app.RGBTab);
            title(app.UIAxes2, 'RGB分量')
            app.UIAxes2.XLim = [0 255];
            app.UIAxes2.XTick = [0 128 255];
            app.UIAxes2.XTickLabel = {'0'; '128'; '255'};
            app.RGBPanel = uipanel(app.RGBTab);
            app.RGBPanel.Title = 'RGB分量显示';
            app.RCheckBox = uicheckbox(app.RGBPanel);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.RCheckBox.Text = {'R'; ''};
            app.RCheckBox.Value = true;
            app.GCheckBox = uicheckbox(app.RGBPanel);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @GCheckBoxValueChanged, true);
            app.GCheckBox.Text = {'G'; ''};
            app.GCheckBox.Value = true;
            app.BCheckBox = uicheckbox(app.RGBPanel);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCheckBoxValueChanged, true);
            app.BCheckBox.Text = {'B'; ''};
            app.BCheckBox.Value = true;
            app.Panel_4 = uipanel(app.RGBTab);
            app.Panel_4.Title = '滑条';
            app.RSliderLabel = uilabel(app.Panel_4);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.Panel_4);
            app.RSlider.Limits = [-20 20];
            app.RSlider.MajorTicks = [];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.RSlider.MinorTicks = [];
            app.GSliderLabel = uilabel(app.Panel_4);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.Panel_4);
            app.GSlider.Limits = [-20 20];
            app.GSlider.MajorTicks = [];
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.GSlider.ValueChangingFcn = createCallbackFcn(app, @GSliderValueChanging, true);
            app.GSlider.MinorTicks = [];
            app.BSliderLabel = uilabel(app.Panel_4);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.Panel_4);
            app.BSlider.Limits = [-20 20];
            app.BSlider.MajorTicks = [];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @BSliderValueChanging, true);
            app.BSlider.MinorTicks = [];
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '    二值化';
            app.ButtonGroup_2 = uibuttongroup(app.Tab_2);
            app.ButtonGroup_2.Title = '二值化方法';
            app.Button_26 = uiradiobutton(app.ButtonGroup_2);
            app.Button_26.Text = '全局阈值';
            app.Button_26.Value = true;
            app.Button_27 = uiradiobutton(app.ButtonGroup_2);
            app.Button_27.Text = '局部自适应阈值';
            app.Button_17 = uiradiobutton(app.ButtonGroup_2);
            app.Button_17.Text = '输入阈值';
            app.ButtonGroup_3 = uibuttongroup(app.Tab_2);
            app.ButtonGroup_3.Title = '二值化阈值';
            app.EditFieldLabel = uilabel(app.ButtonGroup_3);
            app.EditFieldLabel.Text = '';
            app.EditField = uieditfield(app.ButtonGroup_3, 'numeric');
            app.EditField.Editable = 'off';
            app.Slider = uislider(app.ButtonGroup_3);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Button_18 = uibutton(app.Tab_2, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '进行二值化';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '      反色';
            app.Tab_3.ButtonDownFcn = createCallbackFcn(app, @Tab_3ButtonDown, true);
            app.Button_6 = uibutton(app.Tab_3, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Text = '进行反色';
            app.Button_19 = uibutton(app.Tab_3, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '取消反色';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '      镜像';
            app.ButtonGroup_4 = uibuttongroup(app.Tab_4);
            app.ButtonGroup_4.Title = '镜像方向';
            app.Button_8 = uiradiobutton(app.ButtonGroup_4);
            app.Button_8.Text = '上下镜像';
            app.Button_8.Value = true;
            app.Button_9 = uiradiobutton(app.ButtonGroup_4);
            app.Button_9.Text = '左右镜像';
            app.Button_7 = uibutton(app.Tab_4, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '进行倒影';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '      倒影';
            app.ButtonGroup_5 = uibuttongroup(app.Tab_5);
            app.ButtonGroup_5.Title = '模糊距离';
            app.REditField_3Label = uilabel(app.ButtonGroup_5);
            app.REditField_3Label.Text = {'R'; ''};
            app.REditField_3 = uieditfield(app.ButtonGroup_5, 'numeric');
            app.REditField_3.Limits = [0 10];
            app.REditField_3.ValueChangedFcn = createCallbackFcn(app, @REditField_3ValueChanged, true);
            app.RSpinnerLabel = uilabel(app.ButtonGroup_5);
            app.RSpinnerLabel.Text = 'R';
            app.RSpinner = uispinner(app.ButtonGroup_5);
            app.RSpinner.ValueChangingFcn = createCallbackFcn(app, @RSpinnerValueChanging, true);
            app.RSpinner.Limits = [0 10];
            app.RSlider_2Label = uilabel(app.ButtonGroup_5);
            app.RSlider_2Label.Text = 'R';
            app.RSlider_2 = uislider(app.ButtonGroup_5);
            app.RSlider_2.Limits = [0 10];
            app.RSlider_2.MajorTicks = [0 5 10];
            app.RSlider_2.ValueChangingFcn = createCallbackFcn(app, @RSlider_2ValueChanging, true);
            app.Button_10 = uibutton(app.Tab_5, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '进行倒影';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '      浮雕';
            app.Tab_6.ButtonDownFcn = createCallbackFcn(app, @Tab_6ButtonDown, true);
            app.Button_11 = uibutton(app.Tab_6, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '进行浮雕';
            app.ButtonGroup_8 = uibuttongroup(app.Tab_6);
            app.ButtonGroup_8.Title = '浮雕距离';
            app.REditField_4Label = uilabel(app.ButtonGroup_8);
            app.REditField_4Label.Text = {'R'; ''};
            app.REditField_4 = uieditfield(app.ButtonGroup_8, 'numeric');
            app.REditField_4.Limits = [0 10];
            app.REditField_4.ValueChangedFcn = createCallbackFcn(app, @REditField_4ValueChanged, true);
            app.RSpinner_2Label = uilabel(app.ButtonGroup_8);
            app.RSpinner_2Label.Text = 'R';
            app.RSpinner_2 = uispinner(app.ButtonGroup_8);
            app.RSpinner_2.ValueChangingFcn = createCallbackFcn(app, @RSpinner_2ValueChanging, true);
            app.RSpinner_2.Limits = [0 10];
            app.RSlider_3Label = uilabel(app.ButtonGroup_8);
            app.RSlider_3Label.Text = 'R';
            app.RSlider_3 = uislider(app.ButtonGroup_8);
            app.RSlider_3.Limits = [0 10];
            app.RSlider_3.MajorTicks = [0 5 10];
            app.RSlider_3.ValueChangingFcn = createCallbackFcn(app, @RSlider_3ValueChanging, true);
            app.ButtonGroup_9 = uibuttongroup(app.Tab_6);
            app.ButtonGroup_9.Title = '浮雕效果';
            app.Button_20 = uiradiobutton(app.ButtonGroup_9);
            app.Button_20.Text = '彩色浮雕';
            app.Button_20.Value = true;
            app.Button_21 = uiradiobutton(app.ButtonGroup_9);
            app.Button_21.Text = '灰度浮雕';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '    证件照';
            app.ButtonGroup_7 = uibuttongroup(app.Tab_7);
            app.ButtonGroup_7.Title = '排列方式';
            app.EditField_2Label = uilabel(app.ButtonGroup_7);
            app.EditField_2Label.Text = {'行'; ''};
            app.EditField_2 = uieditfield(app.ButtonGroup_7, 'numeric');
            app.EditField_2.Limits = [1 10];
            app.EditField_2.Value = 2;
            app.EditField_3Label = uilabel(app.ButtonGroup_7);
            app.EditField_3Label.Text = {'列'; ''};
            app.EditField_3 = uieditfield(app.ButtonGroup_7, 'numeric');
            app.EditField_3.Limits = [1 10];
            app.EditField_3.Value = 3;
            app.Button_12 = uibutton(app.Tab_7, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '进行排列';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'编辑选项'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'ps.png';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'PhotoStore'};
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_22.Text = '导入图片';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_23.Text = '保存图片';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_24.Text = '还原图片';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_25.Text = '退出';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = photostore

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end