classdef bungee < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Menu         matlab.ui.container.Menu
        Menu_2       matlab.ui.container.Menu
        Menu_3       matlab.ui.container.Menu
        Menu_4       matlab.ui.container.Menu
        Switch       matlab.ui.control.Switch
        Button_3     matlab.ui.control.Button
        TextArea_2   matlab.ui.control.TextArea
        Lamp         matlab.ui.control.Lamp
        Button_2     matlab.ui.control.Button
        ButtonGroup  matlab.ui.container.ButtonGroup
        kButton      matlab.ui.control.RadioButton
        EditField_4  matlab.ui.control.NumericEditField
        EditField_3  matlab.ui.control.NumericEditField
        EditField_2  matlab.ui.control.NumericEditField
        EditField    matlab.ui.control.NumericEditField
        hButton      matlab.ui.control.RadioButton
        LButton      matlab.ui.control.RadioButton
        mButton      matlab.ui.control.RadioButton
        Button       matlab.ui.control.Button
        TextArea     matlab.ui.control.TextArea
        Image2       matlab.ui.control.Image
        Panel        matlab.ui.container.Panel
        CheckBox     matlab.ui.control.CheckBox
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function results = gobungee(app,L,h,k,m)
            global move ka;
            assignin("base",'L',L); % 传入工作区
            assignin("base",'h',h);
            assignin("base",'k',k);
            assignin("base","m",m);
            results=sim("simubungee.slx",'StopTime','30');
            hold(app.UIAxes,'on');
            plot(app.UIAxes,results.tout,results.yout); % 绘制波形
            if move==1
                person=plot(app.UIAxes2,results.yout(1),'bo','markersize',10); % 绘制模拟
                axis(app.UIAxes2,[-5 5 -50 150]);
                for i=1:100:numel(results.yout)
                    if results.yout(i)<0
                        set(person,'ydata',results.yout(i));
                        set(person,'Marker','x','MarkerEdgeColor','r');
                    end
                    set(person,'ydata',results.yout(i));
                    pause(0.005);
                end
            else
                pause(0.005);
            end
        end

        function lockbutton(app)
            % 锁定输入
            app.kButton.Enable='on';
            app.hButton.Enable='on';
            app.LButton.Enable='on';
            app.mButton.Enable='on';
            if app.LButton.Value==1 % ban L
                app.EditField.Enable ='off';
                app.EditField.Editable='off';
                app.EditField_2.Enable='on';
                app.EditField_2.Editable='on';
                app.EditField_3.Enable='on';
                app.EditField_3.Editable='on';
                app.EditField_4.Enable='on';
                app.EditField_4.Editable='on';
            elseif app.hButton.Value==1 % ban h
                app.EditField.Enable ='on';
                app.EditField.Editable='on';
                app.EditField_2.Enable='off';
                app.EditField_2.Editable='off';
                app.EditField_3.Enable='on';
                app.EditField_3.Editable='on';
                app.EditField_4.Enable='on';
                app.EditField_4.Editable='on';
            elseif app.kButton.Value==1 % ban k
                app.EditField.Enable ='on';
                app.EditField.Editable='on';
                app.EditField_2.Enable='on';
                app.EditField_2.Editable='on';
                app.EditField_3.Enable='off';
                app.EditField_3.Editable='off';
                app.EditField_4.Enable='on';
                app.EditField_4.Editable='on';
            else % ban m
                app.EditField.Enable ='on';
                app.EditField.Editable='on';
                app.EditField_2.Enable='on';
                app.EditField_2.Editable='on';
                app.EditField_3.Enable='on';
                app.EditField_3.Editable='on';
                app.EditField_4.Enable='off';
                app.EditField_4.Editable='off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global ka move swt;
            app.Lamp.Enable='off';
            move=app.CheckBox.Value;
            swt=app.Switch.Value;
            if swt=='求解'
                app.lockbutton;
            else
                app.kButton.Enable='off';
                app.hButton.Enable='off';
                app.LButton.Enable='off';
                app.mButton.Enable='off';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global ka move swt;
            swt=app.Switch.Value;
            app.Lamp.Enable='on';
            if strcmp(swt,'求解')
                move = app.CheckBox.Value;
                if app.LButton.Value==1 % 求解L
                    cla(app.UIAxes);
                    h=app.EditField_2.Value;
                    k=app.EditField_3.Value;
                    m=app.EditField_4.Value;
                    for L=1:233-h
                        app.Lamp.Enable='on';
                        app.TextArea_2.Value=['L:',num2str(L)];
                        Lresult=app.gobungee(L,h,k,m);
                        if min(Lresult.yout)<0 % 到达L的临界值
                            break;
                        end
                        if ka==1 % 中断
                            ka=0;
                            break;
                        end
                    end
                    if min(Lresult.yout)<0 % 输出提示
                        str=['绳长最大值为',num2str(L-1)];
                        msgbox({str;'但不建议，会有生命危险'},'结果');
                    else
                        msgbox('已中断','提示');
                    end
                    app.Lamp.Enable='off';
                elseif app.hButton.Value==1 % 求解h
                    cla(app.UIAxes);
                    L=app.EditField.Value;
                    k=app.EditField_3.Value;
                    m=app.EditField_4.Value;
                    for h=100:-1:1
                        app.Lamp.Enable='on';
                        app.TextArea_2.Value=['h:',num2str(h)];
                        hresult=app.gobungee(L,h,k,m);
                        if min(hresult.yout)<0 % 到达h的临界值
                            break;
                        end
                        if ka==1 % 中断
                            ka=0;
                            break;
                        end
                    end
                    if min(hresult.yout)<0 % 输出提示
                        str=['h最小值为',num2str(h-1)];
                        msgbox({str;'但不建议，会有生命危险'},'结果');
                    else
                        msgbox('已中断','提示');
                    end
                    app.Lamp.Enable='off';
                elseif app.kButton.Value==1 % 求解k
                    cla(app.UIAxes);
                    L=app.EditField.Value;
                    h=app.EditField_2.Value;
                    m=app.EditField_4.Value;
                    for k=70:-1:1
                        app.Lamp.Enable='on';
                        app.TextArea_2.Value=['k:',num2str(k)];
                        kresult=app.gobungee(L,h,k,m);
                        if min(kresult.yout)<0 % 到达k的临界值
                            break;
                        end
                        if ka==1 % 中断
                            ka=0;
                            break;
                        end
                    end
                    if min(kresult.yout)<0 % 输出提示
                        str=['k最小值为',num2str(k-1)];
                        msgbox({str;'但不建议，会有生命危险'},'结果');
                    else
                        msgbox('已中断','提示');
                    end
                    app.Lamp.Enable='off';
                else % 求解m
                    cla(app.UIAxes);
                    L=app.EditField.Value;
                    h=app.EditField_2.Value;
                    k=app.EditField_3.Value;
                    for m=40:200
                        app.Lamp.Enable='on';
                        app.TextArea_2.Value=['m:',num2str(m)];
                        mresult=app.gobungee(L,h,k,m);
                        if min(mresult.yout)<0 % 到达L的临界值
                            break;
                        end
                        if ka==1 % 中断
                            ka=0;
                            break;
                        end
                    end
                    if min(mresult.yout)<0 % 输出提示
                        str=['m最大值为',num2str(m-1)];
                        msgbox({str;'但不建议，会有生命危险'},'结果');
                    else
                        msgbox('已中断','提示');
                    end
                    app.Lamp.Enable='off';
                end
            else
                app.kButton.Enable='off';
                app.hButton.Enable='off';
                app.LButton.Enable='off';
                app.mButton.Enable='off';
                app.EditField.Enable ='on';
                app.EditField.Editable='on';
                app.EditField_2.Enable='on';
                app.EditField_2.Editable='on';
                app.EditField_3.Enable='on';
                app.EditField_3.Editable='on';
                app.EditField_4.Enable='on';
                app.EditField_4.Editable='on';
                L=app.EditField.Value;
                h=app.EditField_2.Value;
                k=app.EditField_3.Value;
                m=app.EditField_4.Value;
                cla(app.UIAxes);
                result=app.gobungee(L,h,k,m);
                if min(result.yout)>0 % 输出提示
                    str=['m最大值为',num2str(m-1)];
                    msgbox({'当前参数可以进行蹦极'},'结果');
                else
                    msgbox({'当前参数不可以进行蹦极'},'结果');
                end
                app.Lamp.Enable='off';
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            answer=questdlg('确定退出模拟器?','提示','确定','取消','取消'); % 后悔药提示
            switch answer
                case '确定'
                    delete(app);
                case '取消'
                    return
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            global ka;
            ka=1;
        end

        % Menu selected function: Menu_2
        function Menu_2Selected(app, event)
            msgbox({'蹦极模拟器是一款基于simulink开发的模拟器';'通过开关选择模拟模式','在输入参数一栏选择求解的参数 并输入已知参数';'模拟中断需要等待当次模拟结束后中断';'模拟速度较慢 等:)'},'帮助');
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            msgbox({'刘家彤编写';'首都师范大学';'信息工程学院';'2023年1月7日'},'作者');
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            answer=questdlg('确定退出模拟器?','提示','确定','取消','取消'); % 后悔药提示
            switch answer
                case '确定'
                    delete(app);
                case '取消'
                    return
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            global swt
            swt=app.Switch.Value;
            selectedButton = app.ButtonGroup.SelectedObject;
            app.Lamp.Enable='off';
            if strcmp(swt,'求解')
                app.lockbutton;
            else
                app.kButton.Enable='off';
                app.hButton.Enable='off';
                app.LButton.Enable='off';
                app.mButton.Enable='off';
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            global move;
            move = app.CheckBox.Value;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            global swt;
            swt = app.Switch.Value;
            if strcmp(swt,'测试')
                app.kButton.Enable='off';
                app.hButton.Enable='off';
                app.LButton.Enable='off';
                app.mButton.Enable='off';
                app.EditField.Enable ='on';
                app.EditField.Editable='on';
                app.EditField_2.Enable='on';
                app.EditField_2.Editable='on';
                app.EditField_3.Enable='on';
                app.EditField_3.Editable='on';
                app.EditField_4.Enable='on';
                app.EditField_4.Editable='on';
            else
                app.lockbutton;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '蹦极模拟器';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Menu_2Selected, true);
            app.Menu_2.Text = '帮助';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '作者';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '退出';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '模拟结果';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '离地距离')
            xlabel(app.UIAxes, '时间')
            ylabel(app.UIAxes, '距离(m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Panel);
            title(app.UIAxes2, '运动状态')
            ylabel(app.UIAxes2, '距离 (m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = '示意图.jpg';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'蹦极模拟器'};
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'bungee_jump.png';
            app.Button.Text = '';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '输入参数';
            app.mButton = uiradiobutton(app.ButtonGroup);
            app.mButton.Text = 'm';
            app.LButton = uiradiobutton(app.ButtonGroup);
            app.LButton.Text = 'L';
            app.LButton.Value = true;
            app.hButton = uiradiobutton(app.ButtonGroup);
            app.hButton.Text = 'h';
            app.EditField = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField.Value = 30;
            app.EditField_2 = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField_2.Value = 90;
            app.EditField_3 = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField_3.Value = 20;
            app.EditField_4 = uieditfield(app.ButtonGroup, 'numeric');
            app.EditField_4.Value = 90;
            app.kButton = uiradiobutton(app.ButtonGroup);
            app.kButton.Text = 'k';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '退出';
            app.Lamp = uilamp(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '停止模拟';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'求解', '测试'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '求解';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bungee

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end